classdef CrystalCropperGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DeleteLastSelectionButton  matlab.ui.control.Button
        FrameIndexSpinner          matlab.ui.control.Spinner
        FrameIndexSpinnerLabel     matlab.ui.control.Label
        ResetPointsButton          matlab.ui.control.Button
        OutputFolderField          matlab.ui.control.EditField
        SavingFolderLabel          matlab.ui.control.Label
        SelectFolderButton         matlab.ui.control.Button
        SavedCounterLabel          matlab.ui.control.Label
        NextSecondButton           matlab.ui.control.Button
        PreviousSecondButton       matlab.ui.control.Button
        ConfirmSelectionButton     matlab.ui.control.Button
        FrameLabel                 matlab.ui.control.Label
        CropCrystalButton          matlab.ui.control.Button
        FrameSlider                matlab.ui.control.Slider
        LoadVideoButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

   properties (Access = private)
    VideoObject     % To hold the VideoReader object
    CroppedImage    % To store the currently cropped image
    TotalFrames     % To store the total number of frames
    VideoFileName   % To store the name of the video file
    RectangleROI    % To hold the interactive rectangle object
    SavedCrystalCount = 0; % Counter for saved crystals
    OutputFolderPath = ''; % To store the selected save destination
    SavedFrameIndex = 0;   % To number the output files (Frame_001, etc.)
    PolygonROIs = []; % Array to store all 4-point polygon objects
    StartFrameSpinner       
    StartFrameLabel       
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure the confirm button is disabled on startup
            app.ConfirmSelectionButton.Enable = 'off';
            % Set a default output path when the app starts
            app.OutputFolderPath = fullfile(pwd, 'Cropped_Crystals'); % pwd is the current folder
            if ~exist(app.OutputFolderPath, 'dir')
        % If it doesn't exist, create it
                try
                    mkdir(app.OutputFolderPath);
                    % Display a confirmation message in the MATLAB Command Window
                    disp('Folder was not found and has been created.');
                catch ME
                    % If creation fails (e.g., due to permissions), show an error
                    uialert(app.UIFigure, ['Could not create the output folder. Error: ' ME.message], 'Folder Creation Error');
                end
            end
            app.OutputFolderField.Value = app.OutputFolderPath;
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
   
            % Open file selection dialog for video files
            [file, path] = uigetfile({'*.mp4';'*.avi';'*.mov';'*.*'}, 'Select a Video File');
        
            % Check if the user selected a file
            if isequal(file, 0)
                disp('User canceled the selection.');
                return;
            end

            % Reset the counter for the new session
            app.SavedCrystalCount = 0;
            app.SavedCounterLabel.Text = 'Crystals Saved: 0';
            app.SavedFrameIndex = app.FrameIndexSpinner.Value; % Reset the file naming index
        
            % Construct the full file path
            fullPath = fullfile(path, file);
            app.VideoFileName = file; % Store filename for saving later
        
            % Create a VideoReader object and store it in the app's properties
            app.VideoObject = VideoReader(fullPath);
            app.TotalFrames = app.VideoObject.NumFrames;
        
            % Configure the slider
            app.FrameSlider.Limits = [1 app.TotalFrames];
            app.FrameSlider.Value = 1;
            app.FrameSlider.MajorTicks = round(linspace(1, app.TotalFrames, 10)); % Optional: makes slider look nice
        
            % Read and display the first frame
            currentFrame = readFrame(app.VideoObject);
            imshow(currentFrame, 'Parent', app.UIAxes);
            app.FrameLabel.Text = ['Frame: 1 / ' num2str(app.TotalFrames)];
        end

        % Value changed function: FrameIndexSpinner
        function FrameIndexSpinnerValueChanged(app, event)
                %set the current value as the displayed value
                newValue = app.FrameIndexSpinner.Value;
                
                %Implement the spinner value as frame index for storage
                app.SavedFrameIndex = newValue;

                %Confirmation display in terminal
                disp(['Next save index has been manually set to: ' num2str(app.SavedFrameIndex)]);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
                    % Get the current value of the slider (frame number)
            frameNumber = round(app.FrameSlider.Value);
        
            % Ensure the video object exists
                if ~isempty(app.VideoObject)
                % Read the specific frame
                currentFrame = read(app.VideoObject, frameNumber);
                % Display the frame
                imshow(currentFrame, 'Parent', app.UIAxes);
                % Update the label
                app.FrameLabel.Text = ['Frame: ' num2str(frameNumber) ' / ' num2str(app.TotalFrames)];
                end
        end

        % Button pushed function: CropCrystalButton
        function CropCrystalButtonPushed(app, event)
    
        if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Please load a video first.', 'No Image');
            return;
        end
        
        zoom(app.UIAxes, 'off');
        pan(app.UIAxes, 'off');
        
        % Use the default drawpolygon. The user must double-click to finish.
        newPolygon = drawpolygon(app.UIAxes);
        
        % Immediately check if the user drew exactly 4 points.
        if size(newPolygon.Position, 1) ~= 4
            delete(newPolygon); % Delete the invalid shape
            uialert(app.UIFigure, 'Invalid selection. You must select exactly 4 points. Please try again.', 'Error');
            return;
        end
        
        % Store the valid polygon object in our array
        app.PolygonROIs = [app.PolygonROIs; newPolygon];
        
        % Enable the other buttons
        app.ConfirmSelectionButton.Enable = 'on';
        app.ResetPointsButton.Enable = 'on';
        app.DeleteLastSelectionButton.Enable = 'on';
 
        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)
        
        if isempty(app.PolygonROIs)
            uialert(app.UIFigure, 'You must select at least one crystal first.', 'No Selections');
            return;
        end
    
        hImage = findobj(app.UIAxes, 'Type', 'image');
        currentImageData = hImage.CData;
        [height, width, ~] = size(currentImageData);
        
        % currentFrameNumber = round(app.FrameSlider.Value);
        baseFileName = sprintf('Frame_%03d', app.SavedFrameIndex);
        imageSavePath = fullfile(app.OutputFolderPath, [baseFileName, '.jpg']);
        textSavePath = fullfile(app.OutputFolderPath, [baseFileName, '.txt']);

        fileID = fopen(textSavePath, 'w');
        if fileID == -1
            uialert(app.UIFigure, 'Error opening file for writing.', 'File Error');
            return;
        end
        
        for k = 1:numel(app.PolygonROIs)
            positions = app.PolygonROIs(k).Position;
            
            normalizedCoords = zeros(1, 8);
            for i = 1:4
                normX = positions(i, 1) / width;
                normY = positions(i, 2) / height;
                normalizedCoords((i-1)*2 + 1) = max(0, min(1, normX));
                normalizedCoords((i-1)*2 + 2) = max(0, min(1, normY));
            end
            classID = 0;
            fprintf(fileID, '%d %.6f %.6f %.6f %.6f %.6f %.6f %.6f %.6f\n', classID, normalizedCoords);
       
        end
        
        fclose(fileID);
        imwrite(currentImageData, imageSavePath);
    
        numSaved = numel(app.PolygonROIs);
        app.SavedCrystalCount = app.SavedCrystalCount + numSaved;
        app.SavedCounterLabel.Text = ['Crystals Saved: ' num2str(app.SavedCrystalCount)];
        msgbox([num2str(numSaved) ' crystal annotations saved to ' baseFileName], 'Success');
        
        ResetPointsButtonPushed(app, event);
        % Increment the index by 1 and synchronize spinner display
        app.FrameIndexSpinner.Value  = app.FrameIndexSpinner.Value + 1;
        app.SavedFrameIndex = app.FrameIndexSpinner.Value;
        end

        % Button pushed function: PreviousSecondButton
        function PreviousSecondButtonPushed(app, event)
       
    newValue = app.FrameSlider.Value - 60;
    % Check if we are not below the first frame
    if newValue >= 1
        app.FrameSlider.Value = newValue;
        FrameSliderValueChanged(app);
    end
        end

        % Button pushed function: NextSecondButton
        function NextSecondButtonPushed(app, event)
          
    newValue = app.FrameSlider.Value + 60;
    % Check if we are not past the last frame
    if newValue <= app.FrameSlider.Limits(2)
        app.FrameSlider.Value = newValue;
        FrameSliderValueChanged(app);
    end
        end

        % Button pushed function: DeleteLastSelectionButton
        function DeleteLastSelectionButtonPushed(app, event)
        % Check if any valid crystals are selected
        if ~isempty(app.PolygonROIs)
        % Remove the graphic lines
        delete(app.PolygonROIs(end));
        % Remove the pixel positions stored
        app.PolygonROIs(end) = [];
        % Disable confirm selection button when no crystals selected
        else
        app.ConfirmSelectionButton.Enable = 'off';
        end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
    
        % Open a dialog to let the user select a folder
        % uigetdir starts in the current directory (pwd)
        selectedPath = uigetdir(pwd, 'Select an Output Folder');
        
        % Check if the user selected a folder (and didn't cancel)
        if selectedPath ~= 0
            % Store the path in our property
            app.OutputFolderPath = selectedPath;
            % Update the read-only text field to show the new path
            app.OutputFolderField.Value = app.OutputFolderPath;
        else
            % User canceled, so we do nothing and keep the old path
            disp('Folder selection canceled.');
        end
        end

        % Button pushed function: ResetPointsButton
        function ResetPointsButtonPushed(app, event)
    
        % Loop through and delete all visible polygon objects
        for i = 1:numel(app.PolygonROIs)
            if isvalid(app.PolygonROIs(i))
                delete(app.PolygonROIs(i));
            end
        end
        
        % Reset the property array
        app.PolygonROIs = [];
        
        % Disable the confirm button
        app.ConfirmSelectionButton.Enable = 'off';
        
        disp('All selections for this frame have been reset.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.CropCrystalButton = uibutton(app.UIFigure, 'push');
            app.CropCrystalButton.ButtonPushedFcn = createCallbackFcn(app, @CropCrystalButtonPushed, true);
            app.CropCrystalButton.Text = {'Select Crystal '; '(4 Points)'};
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame: '; '1'};
            app.ConfirmSelectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = {'Confirm'; 'Selection'};
            app.PreviousSecondButton = uibutton(app.UIFigure, 'push');
            app.PreviousSecondButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSecondButtonPushed, true);
            app.PreviousSecondButton.Text = 'Previous Second';
            app.NextSecondButton = uibutton(app.UIFigure, 'push');
            app.NextSecondButton.ButtonPushedFcn = createCallbackFcn(app, @NextSecondButtonPushed, true);
            app.NextSecondButton.Text = 'Next Second';
            app.SavedCounterLabel = uilabel(app.UIFigure);
            app.SavedCounterLabel.Text = 'Frames Saved: 0';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Output Folder';
            app.SavingFolderLabel = uilabel(app.UIFigure);
            app.SavingFolderLabel.Text = 'Saving Folder:';
            app.OutputFolderField = uieditfield(app.UIFigure, 'text');
            app.OutputFolderField.Editable = 'off';
            app.ResetPointsButton = uibutton(app.UIFigure, 'push');
            app.ResetPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPointsButtonPushed, true);
            app.ResetPointsButton.Text = {'Reset All '; 'Selections'};
            app.FrameIndexSpinnerLabel = uilabel(app.UIFigure);
            app.FrameIndexSpinnerLabel.Text = 'Frame Index';
            app.FrameIndexSpinner = uispinner(app.UIFigure);
            app.FrameIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameIndexSpinnerValueChanged, true);
            app.DeleteLastSelectionButton = uibutton(app.UIFigure, 'push');
            app.DeleteLastSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastSelectionButtonPushed, true);
            app.DeleteLastSelectionButton.Text = {'Delete Last'; 'Selection'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrystalCropperGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end